classdef stringVibra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        Tab1           matlab.ui.container.Tab
        titleleft      matlab.ui.control.Label
        titleright     matlab.ui.control.Label
        titleunder     matlab.ui.control.Label
        titleup        matlab.ui.control.Label
        Button1        matlab.ui.control.Button
        Label_70       matlab.ui.control.Label
        tu0            matlab.ui.control.UIAxes
        Tab            matlab.ui.container.Tab
        titleleft_2    matlab.ui.control.Label
        titleright_2   matlab.ui.control.Label
        titleunder_2   matlab.ui.control.Label
        titleup_2      matlab.ui.control.Label
        Button1_3      matlab.ui.control.Button
        Image3         matlab.ui.control.Image
        content1       matlab.ui.control.Label
        Tab_4          matlab.ui.container.Tab
        content2       matlab.ui.control.Label
        titleleft_3    matlab.ui.control.Label
        titleright_3   matlab.ui.control.Label
        titleunder_3   matlab.ui.control.Label
        titleup_3      matlab.ui.control.Label
        Button1_4      matlab.ui.control.Button
        Tab_2          matlab.ui.container.Tab
        Image3_19      matlab.ui.control.Image
        Image3_18      matlab.ui.control.Image
        content3_7     matlab.ui.control.Label
        Image3_17      matlab.ui.control.Image
        content3_6     matlab.ui.control.Label
        content3_5     matlab.ui.control.Label
        Image3_16      matlab.ui.control.Image
        content3_4     matlab.ui.control.Label
        Image3_15      matlab.ui.control.Image
        content3_3     matlab.ui.control.Label
        Image3_14      matlab.ui.control.Image
        content3_2     matlab.ui.control.Label
        content3       matlab.ui.control.Label
        titleleft_4    matlab.ui.control.Label
        titleright_4   matlab.ui.control.Label
        titleunder_4   matlab.ui.control.Label
        titleup_4      matlab.ui.control.Label
        Button1_5      matlab.ui.control.Button
        Image3_4       matlab.ui.control.Image
        Image3_20      matlab.ui.control.Image
        Tab_3          matlab.ui.container.Tab
        Image3_22      matlab.ui.control.Image
        Image3_21      matlab.ui.control.Image
        titleleft_5    matlab.ui.control.Label
        titleright_5   matlab.ui.control.Label
        titleunder_5   matlab.ui.control.Label
        titleup_5      matlab.ui.control.Label
        Button1_6      matlab.ui.control.Button
        Tab4           matlab.ui.container.Tab
        Panel_33       matlab.ui.container.Panel
        l0             matlab.ui.control.Spinner
        mSpinnerLabel  matlab.ui.control.Label
        F0             matlab.ui.control.NumericEditField
        Label_92       matlab.ui.control.Label
        m0             matlab.ui.control.NumericEditField
        Label_91       matlab.ui.control.Label
        Buttonf2_3     matlab.ui.control.Button
        save1          matlab.ui.control.StateButton
        start1         matlab.ui.control.StateButton
        stop1          matlab.ui.control.StateButton
        mu0            matlab.ui.control.NumericEditField
        Label_84       matlab.ui.control.Label
        v0             matlab.ui.control.NumericEditField
        Label_83       matlab.ui.control.Label
        lmd0           matlab.ui.control.NumericEditField
        Label_82       matlab.ui.control.Label
        n0             matlab.ui.control.NumericEditField
        Label_81       matlab.ui.control.Label
        f0             matlab.ui.control.NumericEditField
        Label_80       matlab.ui.control.Label
        u2             matlab.ui.control.UIAxes
        u3             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        runs=true;
        % Y;
        m=0;
        L=0;
        f=0;
        lambda=0;
        n=0;
        F=0;
        v=0;
        mu=0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Buttonf2_3
        function Button1Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab1;
        end

        % Button pushed function: Button1
        function Button1Pushed2(app, event)
            m=app.m0.Value;
            L=app.l0.Value;
            f=app.f0.Value;
            n=app.n0.Value;
            g = 9.8; % 重力加速度(m/s^2)
            lambda=2*L/n;% 波长(m)
            F = m*g; % 弦线张力(N)
            v = f*lambda;%sqrt(F/mu); % 横波波速(m/s)
            k = 2*pi/lambda; % 波数(1/m)
            omega = 2*pi*f; % 角频率(rad/s)
            mu=F*(n/2/L/f)^2;%弦密度(Kg/m)
            app.lmd0.Value=lambda;
            app.F0.Value=F;
            app.v0.Value=v;
            app.mu0.Value=mu;
            % app.u3=axes;set(gcf,'outerposition',get(0, 'screensize'));
            hold(app.tu0,"on");
            axis(app.tu0,"equal");
            view(app.tu0,44.8,33.8);
            axis(app.tu0,[-3.5 3.5 -3.5 8.2 -0.2 0.2]);
            axis(app.tu0,"off");
            % grid on;
            % xlabel('X'); ylabel('Y'); zlabel('Z');
            finess=100;
            % 琴腔
            theta = linspace(0, 2*pi, finess);
            z_plane = zeros(size(theta));
            x_body = 1.5*cos(theta) - 0.3*cos(3*theta);
            y_body = 3*sin(theta) + 0.4*sin(2*theta);
            fill3(app.tu0,x_body, y_body, z_plane, [0.96 0.87 0.7], 'EdgeColor', [0.4 0.3 0.2], 'LineWidth', 2);
            % 音孔
            theta = linspace(0, 2*pi, 50);
            plot3(app.tu0,0.5*cos(theta), 0.5*sin(theta)+0.5, z_plane(1:50), 'Color', [0.4 0.3 0.2], 'LineWidth', 2);
            plot3(app.tu0,0.3*cos(theta), 0.3*sin(theta)+0.5, z_plane(1:50), 'Color', [0.7 0.6 0.5], 'LineWidth', 1.5);
            % 琴颈
            neck_x = [-0.2 -0.2 0.2 0.2];
            head_y = max(y_body);%起点
            neck_y=[head_y-0.4, head_y+3.5, head_y+3.5, head_y-0.4];
            fill3(app.tu0,neck_x, neck_y([1 2 3 4]), z_plane([1 1 1 1]), [0.5 0.35 0.25], 'EdgeColor', [0.3 0.2 0.1]);
            % 品丝
            for idx = 1:12
                y_pos = interp1([1 12], [max(y_body)-0.1 max(y_body)+3.4], f);
                plot3(app.tu0,[-0.18 0.18], [y_pos y_pos], [0 0], 'Color', [0.7 0.6 0.5], 'LineWidth', 1);
            end
            maxbody=head_y;minbody=min(y_body)+1;
            % 琴头
            head_x = [-0.3 -0.3 0.3 0.3];
            head_y = [head_y+3.6 head_y+4.8 head_y+4.8 head_y+3.6];
            fill3(app.tu0,head_x, head_y, z_plane([1 1 1 1]), [0.7 0.5 0.4], 'EdgeColor', 'none');
            % 琴颈线
            for s = 1:2:5%固 L*10+
                x_str = -0.15 + (s-1)*0.075;
                plot3(app.tu0,[x_str x_str], [minbody max(y_body)+4.5], [0 0],...
                    'Color', [0.3 0.3 0.3], 'LineWidth', 2 - 0.2*(s-1));
            end


        end

        % Value changed function: start1
        function start1ValueChanged(app, event)
            delete(app.u3.Children);delete(app.u2.Children);app.runs=true;
            m=app.m0.Value;
            L=app.l0.Value;
            f=app.f0.Value;
            lambda=app.lmd0.Value;
            % m = 0.35; % 砝码质量(kg)
            % L = 0.4; % 弦长(m)
            % f = 220; % 振动频率(Hz)
            finess=100;
            g = 9.8; % 重力加速度(m/s^2)
            n=2*L/lambda;%波节数
            k = 2*pi/lambda; % 波数(1/m)
            omega = 2*pi*f; % 角频率(rad/s)
            maxs=0.2;
            x=linspace(0,L,finess); t=linspace(0,2,300);[X,T]=meshgrid(x,t);%网格
            if n~=fix(n)
                n=inf;F=inf;v=inf;mu=inf;
                Y=maxs*sin(linspace(0,pi,finess)).*cos(omega*T);
            else
                F = m*g; % 弦线张力(N)
                v = 2*L*f/n;%sqrt(F/mu); % 横波波速(m/s)
                mu=F*(n/2/L/f)^2;%弦密度(Kg/m)
                Y = maxs*sin(k*X).*cos(omega*T);%Y(:,end)=0; % 驻波方程
            end
            app.n0.Value=n;
            app.F0.Value=F;
            app.v0.Value=v;
            app.mu0.Value=mu;
            % app.u3=axes;set(gcf,'outerposition',get(0, 'screensize'));
            hold(app.u3,"on");
            axis(app.u3,"equal");
            view(app.u3,44.8,33.8);
            axis(app.u3,[-3.5 3.5 -3.5 9 -7 0.2]);
            axis(app.u3,"off");
            % grid on;
            % xlabel('X'); ylabel('Y'); zlabel('Z');
            finess=100;
            % 琴腔
            theta = linspace(0, 2*pi, finess);
            z_plane = zeros(size(theta));
            x_body = 1.5*cos(theta) - 0.3*cos(3*theta);
            y_body = 3*sin(theta) + 0.4*sin(2*theta);
            fill3(app.u3,x_body, y_body, z_plane, [0.96 0.87 0.7], 'EdgeColor', [0.4 0.3 0.2], 'LineWidth', 2);
            % 音孔
            theta = linspace(0, 2*pi, 50);
            plot3(app.u3,0.5*cos(theta), 0.5*sin(theta)+0.5, z_plane(1:50), 'Color', [0.4 0.3 0.2], 'LineWidth', 2);
            plot3(app.u3,0.3*cos(theta), 0.3*sin(theta)+0.5, z_plane(1:50), 'Color', [0.7 0.6 0.5], 'LineWidth', 1.5);
            % 琴颈
            neck_x = [-0.2 -0.2 0.2 0.2];
            head_y = max(y_body);%起点
            neck_y=[head_y-0.4, head_y+3.5, head_y+3.5, head_y-0.4];
            fill3(app.u3,neck_x, neck_y([1 2 3 4]), z_plane([1 1 1 1]), [0.5 0.35 0.25], 'EdgeColor', [0.3 0.2 0.1]);
            maxbody=head_y;minbody=min(y_body)+1;
            % 品丝
            for idx= 1:12
                y_pos = interp1([1 12], [maxbody-0.1 maxbody+3.4], f);
                plot3(app.u3,[-0.18 0.18], [y_pos y_pos], [0 0], 'Color', [0.7 0.6 0.5], 'LineWidth', 1);
            end
            % 琴头
            head_x = [-0.3 -0.3 0.3 0.3];
            head_y = [head_y+3.6 head_y+4.8 head_y+4.8 head_y+3.6];
            fill3(app.u3,head_x, head_y, z_plane([1 1 1 1]), [0.7 0.5 0.4], 'EdgeColor', 'none');
            %夹头
            temp=L*10+minbody;
            plot3(app.u3,[-0.3,0.3],[temp,temp],[0,0],'Color',[0.93 0.69 0.12],'LineWidth', 1.5);
            % 琴颈线
            for s = 1:2:5%固 L*10+
                if s==3
                    plot3(app.u3,[0 0], [temp maxbody+4.8], [0 0],...
                        'Color', [0.3 0.3 0.3], 'LineWidth', 1.6);
                else
                    x_str = -0.15 + (s-1)*0.075;
                    plot3(app.u3,[x_str x_str], [minbody maxbody+4.5], [0 0],...
                        'Color', [0.3 0.3 0.3], 'LineWidth', 2 - 0.2*(s-1));
                end
            end
            %砝码
            pos1=-1;
            plot3(app.u3,[0 0],[maxbody+4.8 maxbody+4.8],[0 pos1],'k');
            radius = 0.2;% 底面半径
            height = 0.8;% 高度
            pos1=height-pos1;
            [X, Y1, Z] = cylinder(radius, 50);
            Z = Z * height-pos1;
            surf(app.u3,X, Y1+maxbody+4.8, Z, 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'none', 'FaceLighting', 'gouraud');
            theta = linspace(0, 2*pi, 50);%凹槽
            outer_x = radius * cos(theta);
            outer_y = radius * sin(theta)+maxbody+4.8;
            plane=ones(size(theta));
            fill3(app.u3,outer_x, outer_y, height*plane-pos1, [0.7 0.7 0.7], 'FaceLighting', 'gouraud');%顶底面
            fill3(app.u3,outer_x, outer_y, plane-1-pos1, [0.7 0.7 0.7], 'FaceLighting', 'gouraud');
            for z = linspace(0, height, 5)
                plot3(app.u3,outer_x, outer_y, z*plane-pos1,'Color', [0.5 0.5 0.5], 'LineWidth', 1.5);
            end
            %动
            x=x*10+minbody;
            p1=plot3(app.u3,Y(1,:)/5,x,z_plane, 'Color', [0.3 0.3 0.3], 'LineWidth', 1.6);
            % p1.Visible="off";
            % figure;app.u2=axes;
            p2=plot(app.u2,x,Y(1,:)/5,'Color', [0.3 0.3 0.3], 'LineWidth', 1.6);
            axis(app.u2,[x(1) x(end) -0.5 0.5]);
            for i = 2:length(t)
                if app.runs
                    % view(app.u3,44.8,10+i/5);
                    view(app.u3,20+i/6,33.8);
                    temp=Y(i,:);
                    p1.XData=temp/5;
                    p2.YData=temp;
                    pause(0.03);
                end
            end
            hold(app.u3,"off");
            % view(app.u3,44.8,33.8);
            app.m=m;
            app.L=L;
            app.f=f;
            app.lambda=lambda;
            app.n=n;
            app.F=F;
            app.v=v;
            app.mu=mu;
        end

        % Value changed function: save1
        function save1ValueChanged(app, event)
            if exist('data.xlsx','file')
                writecell({app.m,app.L,app.f,app.lambda,app.n,app.F,app.v,app.mu},'data.xlsx','WriteMode','append');
            else
                writecell([{'砝码质量','弦长','振动频率','波长','波节数','弦线张力','波速','弦密度'};{app.m,app.L,app.f,app.lambda,app.n,app.F,app.v,app.mu}],'data.xlsx');
            end

        end

        % Value changed function: stop1
        function stop1ValueChanged(app, event)
            app.runs=false;
            % delete(app.u3.Children);delete(app.u2.Children);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '弦振动仿真实验';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = '主页';
            app.tu0 = uiaxes(app.Tab1);
            zlabel(app.tu0, 'Z')
            app.tu0.XTick = [];
            app.tu0.YTick = [];
            app.Label_70 = uilabel(app.Tab1);
            app.Label_70.Text = '';
            app.Button1 = uibutton(app.Tab1, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed2, true);
            app.Button1.Text = '弦振动仿真实验';
            app.titleup = uilabel(app.Tab1);
            app.titleup.Text = '';
            app.titleunder = uilabel(app.Tab1);
            app.titleunder.Text = '';
            app.titleright = uilabel(app.Tab1);
            app.titleright.Text = '';
            app.titleleft = uilabel(app.Tab1);
            app.titleleft.Text = '';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '实验背景';
            app.content1 = uilabel(app.Tab);
            app.content1.Text = '        驻波实验是大学物理实验中研究波动现象的重要实验之一，其源于对机械波干涉现象的实际观察与理论验证。驻波由两列振幅、频率相同但传播方向相反的相干波叠加形成，实验中通过弦线、声波或电磁波等介质，可直观观察到波节（振幅为零的点）和波腹（振幅最大的点）的稳定分布现象。这一现象不仅验证了波的干涉理论（半波损失、波长与频率关系v=fλ），还在工程中有广泛应用，例如轮胎高速行驶时的驻波效应可能导致爆胎，声学中乐器共振也依赖驻波原理。实验通过调节弦线张力、长度或声波频率，定量分析驻波特性，深化对波动本质的理解。';
            app.Image3 = uiimage(app.Tab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'assert', '054937fa-fc72-4100-a4c7-9d375e959b99.png');
            app.Button1_3 = uibutton(app.Tab, 'push');
            app.Button1_3.Text = '实验背景';
            app.titleup_2 = uilabel(app.Tab);
            app.titleup_2.Text = '';
            app.titleunder_2 = uilabel(app.Tab);
            app.titleunder_2.Text = '';
            app.titleright_2 = uilabel(app.Tab);
            app.titleright_2.Text = '';
            app.titleleft_2 = uilabel(app.Tab);
            app.titleleft_2.Text = '';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '实验目的';
            app.Button1_4 = uibutton(app.Tab_4, 'push');
            app.Button1_4.Text = '实验目的';
            app.titleup_3 = uilabel(app.Tab_4);
            app.titleup_3.Text = '';
            app.titleunder_3 = uilabel(app.Tab_4);
            app.titleunder_3.Text = '';
            app.titleright_3 = uilabel(app.Tab_4);
            app.titleright_3.Text = '';
            app.titleleft_3 = uilabel(app.Tab_4);
            app.titleleft_3.Text = '';
            app.content2 = uilabel(app.Tab_4);
            app.content2.Text = {'(1)掌握用控制变量法验证理论公式:'; '(2)掌握利用驻波原理测量弦线的线密度及驻波的波长和波速;'; '(3)掌握用示波器来观察判断试件是否达到了共振;'; '(4)掌握matlab科学数据处理软件的使用方法。'};
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '实验原理';
            app.Image3_20 = uiimage(app.Tab_2);
            app.Image3_20.ImageSource = fullfile(pathToMLAPP, 'assert', 'p3-8.png');
            app.Image3_4 = uiimage(app.Tab_2);
            app.Image3_4.ImageSource = fullfile(pathToMLAPP, 'assert', 'p3-1.png');
            app.Button1_5 = uibutton(app.Tab_2, 'push');
            app.Button1_5.Text = '实验原理';
            app.titleup_4 = uilabel(app.Tab_2);
            app.titleup_4.Text = '';
            app.titleunder_4 = uilabel(app.Tab_2);
            app.titleunder_4.Text = '';
            app.titleright_4 = uilabel(app.Tab_2);
            app.titleright_4.Text = '';
            app.titleleft_4 = uilabel(app.Tab_2);
            app.titleleft_4.Text = '';
            app.content3 = uilabel(app.Tab_2);
            app.content3.Text = '        驻波是两个振幅相同，频率相同的相干波在同一直线上沿相反方向的传播叠加，当满足一定条件时形成的。实验中，我们把振源定为激振器的刀口处，振动沿着弦线向两边传播，当到达拾振器刀口后通过反射又沿着弦线相向传播，稳定后即可形成驻波。以某点为坐标原点，入射波和反射波的方程分别为';
            app.content3_2 = uilabel(app.Tab_2);
            app.content3_2.Text = '两波满足驻波形成条件时，产生合成波';
            app.Image3_14 = uiimage(app.Tab_2);
            app.Image3_14.ImageSource = fullfile(pathToMLAPP, 'assert', 'p3-2.png');
            app.content3_3 = uilabel(app.Tab_2);
            app.content3_3.Text = '合成波振幅为2Acos(2πx/λ)，在波腹位置处';
            app.Image3_15 = uiimage(app.Tab_2);
            app.Image3_15.ImageSource = fullfile(pathToMLAPP, 'assert', 'p3-3.png');
            app.content3_4 = uilabel(app.Tab_2);
            app.content3_4.Text = '合成波振幅最大。在波节位置处';
            app.Image3_16 = uiimage(app.Tab_2);
            app.Image3_16.ImageSource = fullfile(pathToMLAPP, 'assert', 'p3-4.png');
            app.content3_5 = uilabel(app.Tab_2);
            app.content3_5.Text = '合成波振幅最小。只有当弦长为半波长的整数倍时';
            app.content3_6 = uilabel(app.Tab_2);
            app.content3_6.Text = '才能形成驻波。根据波动理论，横波波速为';
            app.Image3_17 = uiimage(app.Tab_2);
            app.Image3_17.ImageSource = fullfile(pathToMLAPP, 'assert', 'p3-5.png');
            app.content3_7 = uilabel(app.Tab_2);
            app.content3_7.Text = '弦上驻波形成条件的入射波频率为';
            app.Image3_18 = uiimage(app.Tab_2);
            app.Image3_18.ImageSource = fullfile(pathToMLAPP, 'assert', 'p3-6.png');
            app.Image3_19 = uiimage(app.Tab_2);
            app.Image3_19.ImageSource = fullfile(pathToMLAPP, 'assert', 'p3-7.png');
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '实验仪器';
            app.Button1_6 = uibutton(app.Tab_3, 'push');
            app.Button1_6.Text = '实验仪器';
            app.titleup_5 = uilabel(app.Tab_3);
            app.titleup_5.Text = '';
            app.titleunder_5 = uilabel(app.Tab_3);
            app.titleunder_5.Text = '';
            app.titleright_5 = uilabel(app.Tab_3);
            app.titleright_5.Text = '';
            app.titleleft_5 = uilabel(app.Tab_3);
            app.titleleft_5.Text = '';
            app.Image3_21 = uiimage(app.Tab_3);
            app.Image3_21.ImageSource = fullfile(pathToMLAPP, 'assert', 'p4-5.png');
            app.Image3_22 = uiimage(app.Tab_3);
            app.Image3_22.ImageSource = fullfile(pathToMLAPP, 'assert', 'ad6b8560-1a6c-4ebb-bb85-cebe3f47f0aa.png');
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = '仿真过程';
            app.u3 = uiaxes(app.Tab4);
            title(app.u3, '可视化振动过程')
            zlabel(app.u3, 'Z')
            app.u3.XTick = [];
            app.u3.YTick = [];
            app.u2 = uiaxes(app.Tab4);
            title(app.u2, '振动曲线')
            xlabel(app.u2, '位置')
            ylabel(app.u2, '频率')
            zlabel(app.u2, 'Z')
            app.Panel_33 = uipanel(app.Tab4);
            app.Panel_33.Title = '数据面板';
            app.Label_80 = uilabel(app.Panel_33);
            app.Label_80.Text = '振动频率(Hz)';
            app.f0 = uieditfield(app.Panel_33, 'numeric');
            app.f0.ValueDisplayFormat = '%.1f';
            app.f0.Value = 220;
            app.Label_81 = uilabel(app.Panel_33);
            app.Label_81.Text = '波节数';
            app.n0 = uieditfield(app.Panel_33, 'numeric');
            app.n0.ValueDisplayFormat = '%.0f';
            app.Label_82 = uilabel(app.Panel_33);
            app.Label_82.Text = '波长(m)';
            app.lmd0 = uieditfield(app.Panel_33, 'numeric');
            app.lmd0.Limits = [0 Inf];
            app.lmd0.ValueDisplayFormat = '%.4f';
            app.lmd0.Value = 0.3;
            app.Label_83 = uilabel(app.Panel_33);
            app.Label_83.Text = '横波波速(m/s)';
            app.v0 = uieditfield(app.Panel_33, 'numeric');
            app.v0.Limits = [0 Inf];
            app.v0.ValueDisplayFormat = '%.0f';
            app.v0.Value = 79;
            app.Label_84 = uilabel(app.Panel_33);
            app.Label_84.Text = '线密度(kg/m)';
            app.mu0 = uieditfield(app.Panel_33, 'numeric');
            app.mu0.Limits = [0 Inf];
            app.stop1 = uibutton(app.Panel_33, 'state');
            app.stop1.ValueChangedFcn = createCallbackFcn(app, @stop1ValueChanged, true);
            app.stop1.Text = '停止仿真';
            app.start1 = uibutton(app.Panel_33, 'state');
            app.start1.ValueChangedFcn = createCallbackFcn(app, @start1ValueChanged, true);
            app.start1.Text = '开始仿真';
            app.save1 = uibutton(app.Panel_33, 'state');
            app.save1.ValueChangedFcn = createCallbackFcn(app, @save1ValueChanged, true);
            app.save1.Text = '保存数据';
            app.Buttonf2_3 = uibutton(app.Panel_33, 'push');
            app.Buttonf2_3.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Buttonf2_3.Text = '返回主界面';
            app.Label_91 = uilabel(app.Panel_33);
            app.Label_91.Text = '砝码质量(kg)';
            app.m0 = uieditfield(app.Panel_33, 'numeric');
            app.m0.Limits = [0 Inf];
            app.m0.Value = 0.35;
            app.Label_92 = uilabel(app.Panel_33);
            app.Label_92.Text = '弦线张力(N)';
            app.F0 = uieditfield(app.Panel_33, 'numeric');
            app.F0.Limits = [0 Inf];
            app.F0.ValueDisplayFormat = '%.0f';
            app.F0.Value = 12;
            app.mSpinnerLabel = uilabel(app.Panel_33);
            app.mSpinnerLabel.Text = '弦长(m)';
            app.l0 = uispinner(app.Panel_33);
            app.l0.Step = 0.05;
            app.l0.Limits = [0 Inf];
            app.l0.Value = 0.25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stringVibra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end